classdef ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NAMEFRAGEZEICHEN  matlab.ui.Figure
        SCANButton        matlab.ui.control.Button
        TAUSCHButton      matlab.ui.control.Button
        soll1             matlab.ui.control.Image
        soll2             matlab.ui.control.Image
        soll3             matlab.ui.control.Image
        soll4             matlab.ui.control.Image
        soll5             matlab.ui.control.Image
        soll6             matlab.ui.control.Image
        soll7             matlab.ui.control.Image
        soll8             matlab.ui.control.Image
        soll9             matlab.ui.control.Image
        ist1              matlab.ui.control.Image
        ist2              matlab.ui.control.Image
        ist3              matlab.ui.control.Image
        ist4              matlab.ui.control.Image
        ist5              matlab.ui.control.Image
        ist6              matlab.ui.control.Image
        ist7              matlab.ui.control.Image
        ist8              matlab.ui.control.Image
        ist9              matlab.ui.control.Image
        CLOSEButton       matlab.ui.control.Button
        ERSTSCANLabel     matlab.ui.control.Label
        finish            matlab.ui.control.Image
        RandomButton      matlab.ui.control.Button
        KlickenumFarbederSchafezundernLabel  matlab.ui.control.Label
    end


    properties (Access = public)
       
        rob = VirtualRobot('random')
        
        soll 
        ist
        tmatrix
        
        
        s1 = 1;
        s2 = 1;
        s3 = 1;
        s4 = 1;
        s5 = 1;
        s6 = 1;
        s7 = 1;
        s8 = 1;
        s9 = 1;
        
        i1 = 1;
        i2 = 1;
        i3 = 1;
        i4 = 1;
        i5 = 1;
        i6 = 1;
        i7 = 1;
        i8 = 1;
        i9 = 1;
       
        red = 'red.png'
        green = 'green.png'
        blue = 'blue.png'
        white = 'white.png'
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SCANButton
        function SCANButtonPushed(app, event)
            app.SCANButton.Enable
            app.ist = M_Scan(app.rob); %app.ist Matrix die die Farbwerte der gescannten Würfel enthält (1-3)
            
            app.i1 = app.ist(1,1); 
            app.i2 = app.ist(1,2);
            app.i3 = app.ist(1,3);
            app.i4 = app.ist(2,1);
            app.i5 = app.ist(2,2);
            app.i6 = app.ist(2,3);
            app.i7 = app.ist(3,1);
            app.i8 = app.ist(3,2);
            app.i9 = app.ist(3,3); %Farbwerte der Matrix werden in eigene Variablen übertragen
            
           for sheep = 1:9
               if eval(strcat('app.i',num2str(sheep)))== 1
               eval(strcat('app.ist',num2str(sheep),'.ImageSource =','app.red'));
               end  
                if eval(strcat('app.i',num2str(sheep)))== 2
               eval(strcat('app.ist',num2str(sheep),'.ImageSource=','app.green'));
                end   
                 if eval(strcat('app.i',num2str(sheep)))== 3
               eval(strcat('app.ist',num2str(sheep),'.ImageSource=','app.blue')); %Farbindikatoren auf der "IST" Seite erhalten ihre Entsprechende Farbe
                 end   
               pause(0.25)
           end
        end

        % Button pushed function: TAUSCHButton
        function TAUSCHButtonPushed(app, event)
         
            app.soll = [app.s1,app.s2,app.s3;app.s4,app.s5,app.s6;app.s7,app.s8,app.s9];
            app.ist  = [app.i1,app.i2,app.i3;app.i4,app.i5,app.i6;app.i7,app.i8,app.i9]; %Linke und Rechte Seite werden in Matrizen übertragen
           
      
            app.tmatrix = tausch(app.ist,app.soll,app.rob); %Tauschfunktion wird aufgerufen
            
            disp(app.soll);
            disp(app.ist);
            disp(app.tmatrix);
            %msgbox(num2str([app.tmatrix(1,1),app.tmatrix(1,2),app.tmatrix(1,3),app.tmatrix(2,1),app.tmatrix(2,2),app.tmatrix(2,3),app.tmatrix(3,1),app.tmatrix(3,2),app.tmatrix(3,3)]))
            
            
            app.i1 = app.tmatrix(1,1);
            app.i2 = app.tmatrix(1,2);
            app.i3 = app.tmatrix(1,3);  
            app.i4 = app.tmatrix(2,1);
            app.i5 = app.tmatrix(2,2);
            app.i6 = app.tmatrix(2,3);
            app.i7 = app.tmatrix(3,1);
            app.i8 = app.tmatrix(3,2);
            app.i9 = app.tmatrix(3,3); %Neue Getauschte Farbmatrix wird in farbvariablen übertragen

            for sheep = 1:9
               if eval(strcat('app.i',num2str(sheep)))== 1
               eval(strcat('app.ist',num2str(sheep),'.ImageSource =','app.red'));
               end  
                if eval(strcat('app.i',num2str(sheep)))== 2
               eval(strcat('app.ist',num2str(sheep),'.ImageSource=','app.green'));
                end   
                 if eval(strcat('app.i',num2str(sheep)))== 3
               eval(strcat('app.ist',num2str(sheep),'.ImageSource=','app.blue')); % Farbe der Farbindikatoren wird aktualisiert
                 end   
               pause(0.25)
            end
            
            disp([app.i1,app.i2,app.i3,app.i4,app.i5,app.i6,app.i7,app.i8,app.i9,])
            disp([app.s1,app.s2,app.s3,app.s4,app.s5,app.s6,app.s7,app.s8,app.s9,])
        end

        % Image clicked function: finish
        function finishClicked(app, event)
fist = [app.i1,app.i2,app.i3,app.i4,app.i5,app.i6,app.i7,app.i8,app.i9,];
fsoll = [app.s1,app.s2,app.s3,app.s4,app.s5,app.s6,app.s7,app.s8,app.s9,];

for x=1:9
    
    if fist(x) ~= fsoll(x)
        disp(['wird gedreht',num2str(x)])
        Rot_Nav(x,fsoll(x),app.rob)
        eval(strcat('app.i',num2str(x),'=fsoll(',num2str(x),')'));
        
        
         if eval(strcat('app.i',num2str(x)))== 1
               eval(strcat('app.ist',num2str(x),'.ImageSource =','app.red'));
         end           
         if eval(strcat('app.i',num2str(x)))== 2
               eval(strcat('app.ist',num2str(x),'.ImageSource=','app.green'));
         end         
         if eval(strcat('app.i',num2str(x)))== 3
               eval(strcat('app.ist',num2str(x),'.ImageSource=','app.blue')); % Farbe der Farbindikatoren wird aktualisiert
         end         
    end

end

disp('FINISHED')

        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
          randf = randi(3,3,3);
          
            app.s1 = randf(1,1);
            app.s2 = randf(1,2);
            app.s3 = randf(1,3);  
            app.s4 = randf(2,1);
            app.s5 = randf(2,2);
            app.s6 = randf(2,3);
            app.s7 = randf(3,1);
            app.s8 = randf(3,2);
            app.s9 = randf(3,3);
            
            for sheep = 1:9
                
                 eval(strcat('app.soll',num2str(sheep),'.ImageSource =','app.white'));
                 pause(0.25)
                
            end
            
            for sheep = 1:9
               if eval(strcat('app.s',num2str(sheep)))== 1
               eval(strcat('app.soll',num2str(sheep),'.ImageSource =','app.red'));
               end  
                if eval(strcat('app.s',num2str(sheep)))== 2
               eval(strcat('app.soll',num2str(sheep),'.ImageSource=','app.green'));
                end   
                 if eval(strcat('app.s',num2str(sheep)))== 3
               eval(strcat('app.soll',num2str(sheep),'.ImageSource=','app.blue')); % Farbe der Farbindikatoren wird aktualisiert
                 end   
               pause(0.25)
            end
            
          
        end

        % Image clicked function: soll1
        function soll1ImageClicked(app, event)
                  app.s1 = app.s1+1;
            if app.s1 == 1
                app.soll1.ImageSource = 'red.png';
               
            end
            
            if app.s1 == 2 
                app.soll1.ImageSource = 'green.png';
               
            end
            
            if app.s1 == 3
                app.soll1.ImageSource = 'blue.png';
                
            end
            
            if app.s1 == 4
                app.s1 = 1;
                app.soll1.ImageSource = 'red.png'; %Durchwechseln der Farben durch Klick auf jeweiliges Element, untere Funktionen identisch
                
            end
            app.i1 = app.ist(1,1);
        end

        % Image clicked function: soll2
        function soll2ImageClicked(app, event)
                        app.s2 = app.s2+1;
            if app.s2 == 1
                app.soll2.ImageSource = 'red.png';
               
            end
            
            if app.s2 == 2 
                app.soll2.ImageSource = 'green.png';
               
            end
            
            if app.s2 == 3
                app.soll2.ImageSource = 'blue.png';
                
            end
            
            if app.s2 == 4
                app.s2 = 1;
                app.soll2.ImageSource = 'red.png';
                
            end
            app.i2 = app.ist(1,2);
        end

        % Image clicked function: soll3
        function soll3ImageClicked(app, event)
                        app.s3 = app.s3+1;
            if app.s3 == 1
                app.soll3.ImageSource = 'red.png';
               
            end
            
            if app.s3 == 2 
                app.soll3.ImageSource = 'green.png';
               
            end
            
            if app.s3 == 3
                app.soll3.ImageSource = 'blue.png';
                
            end
            
            if app.s3 == 4
                app.s3 = 1;
                app.soll3.ImageSource = 'red.png';
                
            end
            app.i3 = app.ist(1,3);
        end

        % Image clicked function: soll4
        function soll4ImageClicked(app, event)
                        app.s4 = app.s4+1;
            if app.s4 == 1
                app.soll4.ImageSource = 'red.png';
               
            end
            
            if app.s4 == 2 
                app.soll4.ImageSource = 'green.png';
               
            end
            
            if app.s4 == 3
                app.soll4.ImageSource = 'blue.png';
                
            end
            
            if app.s4 == 4
                app.s4 = 1;
                app.soll4.ImageSource = 'red.png';
                
            end
            app.i4 = app.ist(2,1);
        end

        % Image clicked function: soll5
        function soll5ImageClicked(app, event)
                        app.s5 = app.s5+1;
            if app.s5 == 1
                app.soll5.ImageSource = 'red.png';
               
            end
            
            if app.s5 == 2 
                app.soll5.ImageSource = 'green.png';
               
            end
            
            if app.s5 == 3
                app.soll5.ImageSource = 'blue.png';
                
            end
            
            if app.s5 == 4
                app.s5 = 1;
                app.soll5.ImageSource = 'red.png';
                
            end
            app.i5 = app.ist(2,2);
        end

        % Image clicked function: soll6
        function soll6ImageClicked(app, event)
                        app.s6 = app.s6+1;
            if app.s6 == 1
                app.soll6.ImageSource = 'red.png';
               
            end
            
            if app.s6 == 2 
                app.soll6.ImageSource = 'green.png';
               
            end
            
            if app.s6 == 3
                app.soll6.ImageSource = 'blue.png';
                
            end
            
            if app.s6 == 4
                app.s6 = 1;
                app.soll6.ImageSource = 'red.png';
                
            end
            app.i6 = app.ist(2,3);
        end

        % Image clicked function: soll7
        function soll7ImageClicked(app, event)
                        app.s7 = app.s7+1;
            if app.s7 == 1
                app.soll7.ImageSource = 'red.png';
               
            end
            
            if app.s7 == 2 
                app.soll7.ImageSource = 'green.png';
               
            end
            
            if app.s7 == 3
                app.soll7.ImageSource = 'blue.png';
                
            end
            
            if app.s7 == 4
                app.s7 = 1;
                app.soll7.ImageSource = 'red.png';
                
            end
            app.i7 = app.ist(3,1);
        end

        % Image clicked function: soll8
        function soll8ImageClicked(app, event)
                        app.s8 = app.s8+1;
            if app.s8 == 1
                app.soll8.ImageSource = 'red.png';
               
            end
            
            if app.s8 == 2 
                app.soll8.ImageSource = 'green.png';
               
            end
            
            if app.s8 == 3
                app.soll8.ImageSource = 'blue.png';
                
            end
            
            if app.s8 == 4
                app.s8 = 1;
                app.soll8.ImageSource = 'red.png';
                
            end
            app.i8 = app.ist(3,2);
        end

        % Image clicked function: soll9
        function soll9ImageClicked(app, event)
                        app.s9 = app.s9+1;
            if app.s9 == 1
                app.soll9.ImageSource = 'red.png';
               
            end
            
            if app.s9 == 2 
                app.soll9.ImageSource = 'green.png';
               
            end
            
            if app.s9 == 3
                app.soll9.ImageSource = 'blue.png';
                
            end
            
            if app.s9 == 4
                app.s9 = 1;
                app.soll9.ImageSource = 'red.png';
                
            end
            app.i9 = app.ist(3,3);
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            
            close all hidden
            
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NAMEFRAGEZEICHEN = uifigure('Visible', 'off');
            app.NAMEFRAGEZEICHEN.Name = 'NAME_FRAGEZEICHEN';
            app.SCANButton = uibutton(app.NAMEFRAGEZEICHEN, 'push');
            app.SCANButton.ButtonPushedFcn = createCallbackFcn(app, @SCANButtonPushed, true);
            app.SCANButton.Text = 'SCAN';
            app.TAUSCHButton = uibutton(app.NAMEFRAGEZEICHEN, 'push');
            app.TAUSCHButton.ButtonPushedFcn = createCallbackFcn(app, @TAUSCHButtonPushed, true);
            app.TAUSCHButton.Text = {'TAUSCH'; ''};
            app.soll1 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll1.ImageClickedFcn = createCallbackFcn(app, @soll1ImageClicked, true);
            app.soll1.ImageSource = 'red.png';
            app.soll2 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll2.ImageClickedFcn = createCallbackFcn(app, @soll2ImageClicked, true);
            app.soll2.ImageSource = 'red.png';
            app.soll3 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll3.ImageClickedFcn = createCallbackFcn(app, @soll3ImageClicked, true);
            app.soll3.ImageSource = 'red.png';
            app.soll4 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll4.ImageClickedFcn = createCallbackFcn(app, @soll4ImageClicked, true);
            app.soll4.ImageSource = 'red.png';
            app.soll5 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll5.ImageClickedFcn = createCallbackFcn(app, @soll5ImageClicked, true);
            app.soll5.ImageSource = 'red.png';
            app.soll6 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll6.ImageClickedFcn = createCallbackFcn(app, @soll6ImageClicked, true);
            app.soll6.ImageSource = 'red.png';
            app.soll7 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll7.ImageClickedFcn = createCallbackFcn(app, @soll7ImageClicked, true);
            app.soll7.ImageSource = 'red.png';
            app.soll8 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll8.ImageClickedFcn = createCallbackFcn(app, @soll8ImageClicked, true);
            app.soll8.ImageSource = 'red.png';
            app.soll9 = uiimage(app.NAMEFRAGEZEICHEN);
            app.soll9.ImageClickedFcn = createCallbackFcn(app, @soll9ImageClicked, true);
            app.soll9.ImageSource = 'red.png';
            app.ist1 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist1.ImageSource = 'white.png';
            app.ist2 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist2.ImageSource = 'white.png';
            app.ist3 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist3.ImageSource = 'white.png';
            app.ist4 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist4.ImageSource = 'white.png';
            app.ist5 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist5.ImageSource = 'white.png';
            app.ist6 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist6.ImageSource = 'white.png';
            app.ist7 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist7.ImageSource = 'white.png';
            app.ist8 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist8.ImageSource = 'white.png';
            app.ist9 = uiimage(app.NAMEFRAGEZEICHEN);
            app.ist9.ImageSource = 'white.png';
            app.CLOSEButton = uibutton(app.NAMEFRAGEZEICHEN, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.ERSTSCANLabel = uilabel(app.NAMEFRAGEZEICHEN);
            app.ERSTSCANLabel.Text = {'ERST SCAN!'; ''};
            app.finish = uiimage(app.NAMEFRAGEZEICHEN);
            app.finish.ImageClickedFcn = createCallbackFcn(app, @finishClicked, true);
            app.finish.ImageSource = 'finish.png';
            app.RandomButton = uibutton(app.NAMEFRAGEZEICHEN, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = {'Random'; ''};
            app.KlickenumFarbederSchafezundernLabel = uilabel(app.NAMEFRAGEZEICHEN);
            app.KlickenumFarbederSchafezundernLabel.Text = 'Klicken um Farbe der Schafe zu ändern';
            % Show the figure after all components are created
            app.NAMEFRAGEZEICHEN.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NAMEFRAGEZEICHEN)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NAMEFRAGEZEICHEN)
        end
    end
end